classdef mainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWin                      matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadDataMenu                 matlab.ui.container.Menu
        LoadfromRawMenu              matlab.ui.container.Menu
        LoadfromGUIstateMenu         matlab.ui.container.Menu
        DeleteDataMenu               matlab.ui.container.Menu
        DeleteDatasetsMenu           matlab.ui.container.Menu
        DeleteTrialsMenu             matlab.ui.container.Menu
        DeleteCellsMenu              matlab.ui.container.Menu
        ExportDataMenu               matlab.ui.container.Menu
        ExportFullMenu               matlab.ui.container.Menu
        ExporCurSelMenu              matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        SetDefaultsMenu              matlab.ui.container.Menu
        GUIMenu                      matlab.ui.container.Menu
        GeneralPlottingMenu          matlab.ui.container.Menu
        RateMapsMenu                 matlab.ui.container.Menu
        RestoreMenu                  matlab.ui.container.Menu
        PauseUpdatesMenu             matlab.ui.container.Menu
        FreezeMenu                   matlab.ui.container.Menu
        UnFreezeMenu                 matlab.ui.container.Menu
        FiguresMenu                  matlab.ui.container.Menu
        SaveasPDFMenu                matlab.ui.container.Menu
        AllMenu                      matlab.ui.container.Menu
        GUIStateMenu                 matlab.ui.container.Menu
        CustomSelectionMenu          matlab.ui.container.Menu
        NeuropixelsMenu              matlab.ui.container.Menu
        CellsMenu                    matlab.ui.container.Menu
        plotWaveformMenu             matlab.ui.container.Menu
        plotWaveDistonProbeMenu      matlab.ui.container.Menu
        DACQMenu                     matlab.ui.container.Menu
        FilterMenu                   matlab.ui.container.Menu
        ByClustLabelMenu             matlab.ui.container.Menu
        BySpatialPropsMenu           matlab.ui.container.Menu
        spatialInfoMenu              matlab.ui.container.Menu
        gridnessMenu                 matlab.ui.container.Menu
        rayleighvectorMenu           matlab.ui.container.Menu
        MakeCustomFilterMenu         matlab.ui.container.Menu
        RemoveFilterMenu             matlab.ui.container.Menu
        AnalysisMenu                 matlab.ui.container.Menu
        spatialpropsMenu             matlab.ui.container.Menu
        gridnessMenu_2               matlab.ui.container.Menu
        spatialinfoMenu              matlab.ui.container.Menu
        rayleighvectorlengthMenu     matlab.ui.container.Menu
        spatialcorrelationMenu       matlab.ui.container.Menu
        TrialsinDatasetListBoxLabel  matlab.ui.control.Label
        TrialsinDatasetListBox       matlab.ui.control.ListBox
        CellsinDatasetListBoxLabel   matlab.ui.control.Label
        CellsinDatasetListBox        matlab.ui.control.ListBox
        ExportDataButton             matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        OverviewTab                  matlab.ui.container.Tab
        wfs                          matlab.ui.control.UIAxes
        ac_shortLag                  matlab.ui.control.UIAxes
        spikepos                     matlab.ui.control.UIAxes
        rmap                         matlab.ui.control.UIAxes
        varmap                       matlab.ui.control.UIAxes
        dmap                         matlab.ui.control.UIAxes
        nSpikesTxt                   matlab.ui.control.EditField
        nSpikes                      matlab.ui.control.NumericEditField
        amplitudeTxt                 matlab.ui.control.EditField
        isodTxt                      matlab.ui.control.EditField
        lratioTxt                    matlab.ui.control.EditField
        amplitude                    matlab.ui.control.NumericEditField
        lratio                       matlab.ui.control.NumericEditField
        isod                         matlab.ui.control.NumericEditField
        MakeRMapsButton              matlab.ui.control.Button
        varmapButton                 matlab.ui.control.Button
        LoadWaveForms                matlab.ui.control.Button
        MakeDMapsButton              matlab.ui.control.Button
        ac_longLag                   matlab.ui.control.UIAxes
        PanelAllTrials               matlab.ui.container.Panel
        SelectPlotstyleLabel         matlab.ui.control.Label
        varMapList                   matlab.ui.control.ListBox
        cluLabelTxt                  matlab.ui.control.EditField
        cluLabel                     matlab.ui.control.EditField
        CompareCellsTab              matlab.ui.container.Tab
        ac1                          matlab.ui.control.UIAxes
        wf3                          matlab.ui.control.UIAxes
        cg2_3                        matlab.ui.control.UIAxes
        cg1_3                        matlab.ui.control.UIAxes
        ac2                          matlab.ui.control.UIAxes
        cg1_2                        matlab.ui.control.UIAxes
        wf1                          matlab.ui.control.UIAxes
        wf2                          matlab.ui.control.UIAxes
        CC_cell_ID1                  matlab.ui.control.EditField
        CC_cell_ID2                  matlab.ui.control.EditField
        binSzEditFieldLabel          matlab.ui.control.Label
        CC_binSzEditField            matlab.ui.control.NumericEditField
        lagEditFieldLabel            matlab.ui.control.Label
        CC_lagEditField              matlab.ui.control.NumericEditField
        ac3                          matlab.ui.control.UIAxes
        CC_cell_ID3                  matlab.ui.control.EditField
        CC_cell_ID2_2                matlab.ui.control.EditField
        CC_cell_ID3_3                matlab.ui.control.EditField
        TextArea                     matlab.ui.control.TextArea
        PlotCGSelectionButton        matlab.ui.control.Button
        PlotWFSelectionButton        matlab.ui.control.Button
        PlotClusterSpaceButton       matlab.ui.control.Button
        CC_cell_ID1_1                matlab.ui.control.EditField
        CC_maxChanCell1              matlab.ui.control.EditField
        CC_maxChanCell2              matlab.ui.control.EditField
        CC_maxChanCell3              matlab.ui.control.EditField
        LineariseItTab               matlab.ui.container.Tab
        TextArea_2                   matlab.ui.control.TextArea
        TrackLengthcmEditFieldLabel  matlab.ui.control.Label
        TrackLengthEditField         matlab.ui.control.EditField
        TrackTypeDropDownLabel       matlab.ui.control.Label
        TrackTypeDropDown            matlab.ui.control.DropDown
        LineariseItButton            matlab.ui.control.Button
        lin_allCells                 matlab.ui.control.UIAxes
        lin_spikebypos               matlab.ui.control.UIAxes
        lin_rate                     matlab.ui.control.UIAxes
        Lin_cellID                   matlab.ui.control.EditField
        TextArea_3                   matlab.ui.control.TextArea
        Lin_trialID                  matlab.ui.control.EditField
        RundirectionDropDownLabel    matlab.ui.control.Label
        RundirectionDropDown         matlab.ui.control.DropDown
        PlotSelectionButton          matlab.ui.control.Button
        LoadDataButton               matlab.ui.control.Button
        DatasetLabel                 matlab.ui.control.Label
        CurrentDatasetEditField      matlab.ui.control.EditField
        TrialEditFieldLabel          matlab.ui.control.Label
        TrialEditField               matlab.ui.control.EditField
        CellEditFieldLabel           matlab.ui.control.Label
        CellEditField                matlab.ui.control.EditField
        SelectDatasetsListBoxLabel   matlab.ui.control.Label
        SelectDatasetsListBox        matlab.ui.control.ListBox
        SelectAllCells               matlab.ui.control.Button
        SelectAllTrials              matlab.ui.control.Button
        ExportFiguresButton          matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        SelectAllDatasets            matlab.ui.control.Button
    end

       
    % helpers
    methods (Access = private)
        
        function setFieldsGUI(app,event)
            
            if isempty(app.mainWin.UserData.dataObj)
                return;
            end
            
            currDsSelInd = app.mainWin.UserData.currSelInd(1);
            currTrSelInd = app.mainWin.UserData.currSelInd(2);
            currCeSelInd = app.mainWin.UserData.currSelInd(3);
            
            app.CurrentDatasetEditField.Value = app.mainWin.UserData.dataObj{currDsSelInd}.dataSetName;
            app.SelectDatasetsListBox.Value   = app.mainWin.UserData.dataObj{currDsSelInd}.dataSetName;
            % trial & cell selection
            app.TrialEditField.Value        = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames{currTrSelInd};
%             app.CellEditField.Value         = app.mainWin.UserData.cellIDStr{currDsSelInd}{currCeSelInd};
            
            % special case if dataset selection changed or GUI is started
            if isempty(event) || strcmpi(event.Source.Tag,'dsselbox') || isempty(app.TrialsinDatasetListBox.Items) || isempty(app.CellsinDatasetListBox.Items)
                app.TrialsinDatasetListBox.Items = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames;
                app.TrialsinDatasetListBox.Value = app.mainWin.UserData.dataObj{currDsSelInd}.trialNames{1};
                currFilter   = app.mainWin.UserData.Filter{currDsSelInd};
                app.CellsinDatasetListBox.Items  = app.mainWin.UserData.cellIDStr{currDsSelInd}(currFilter); % this will make sure we keep memory of any filter set in case we load multiple datasets - is that useful?
                app.CellsinDatasetListBox.Value  = app.CellsinDatasetListBox.Items{1};
            end
            
            app.CellEditField.Value = app.CellsinDatasetListBox.Value{1};
            
            % only activate 'RemoveFilter' option if you filtered some data
            if sum(app.mainWin.UserData.Filter{currDsSelInd}) < length(app.mainWin.UserData.cellIDStr{currDsSelInd})
                app.RemoveFilterMenu.Enable = 'On';
            else
                app.RemoveFilterMenu.Enable = 'Off';
            end
            % different tabs
            switch lower(app.TabGroup.SelectedTab.Title)
                case 'overview'

                    app.nSpikes.Value   = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_Times{currTrSelInd}{currCeSelInd});
                    if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{1})
                        tempWFs         = squeeze(nanmean(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{currCeSelInd,1},1));
                        if ~all(isnan(tempWFs))
                            app.amplitude.Value  = round(max(abs(min(tempWFs,[],1) - max(tempWFs,[],1))),1);
                        else
                             app.amplitude.Value = 0;
                        end
                    end
                    if strcmp(app.mainWin.UserData.dataType,'scanpix.npix')
                        app.cluLabel.Value = app.mainWin.UserData.dataObj{currDsSelInd}.cell_Label{currCeSelInd};
                    end
                    %             app.lratio.Value    = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_times{currTrSelInd}{currCeSelInd});
                    %             app.isoD.Value      = length(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_times{currTrSelInd}{currCeSelInd});
                    
                    % update figures
                    if app.ac_shortLag.UserData.plot 
                        plotCG(app,currCeSelInd,app.mainWin.UserData.figDefaults.ac_bSz_shortLag,app.mainWin.UserData.figDefaults.ac_lag_shortLag,app.ac_shortLag); 
                    else
                        cla(app.ac_shortLag); 
                        app.ac_shortLag.Visible='On'; 
                    end
                    if app.ac_longLag.UserData.plot 
                        plotCG(app,currCeSelInd,app.mainWin.UserData.figDefaults.ac_bSz_longLag,app.mainWin.UserData.figDefaults.ac_lag_longLag,app.ac_longLag); 
                    else
                        cla(app.ac_longLag); 
                        app.ac_longLag.Visible='On'; 
                    end
                    if app.wfs.UserData.plot;         plotWave(app,currCeSelInd,app.wfs); else; cla(app.wfs);         app.wfs.Visible='On';       end
                    if app.rmap.UserData.plot;        plotRMap(app);                      else; cla(app.rmap);        app.rmap.Visible='On';      end
                    if app.dmap.UserData.plot;        plotDMap(app);                      else; cla(app.dmap);        app.dmap.Visible='On';      end
                    if app.spikepos.UserData.plot;    plotSpkPos(app);                    else; cla(app.spikepos);    app.spikepos.Visible='On';  end
                    
                    if ~isempty(event) && ~strcmpi(event.Source.Tag,'trialselbox')
                        plotAllTrials(app);
                    end
                    
                    
                    if app.varmap.UserData.plot
                        switch lower(app.varMapList.Value)
                            case 'spatial ac'
                                plot_sACs(app);
                            case 'object vect'
                                plot_OVmaps(app);
                            case 'speed mod.'
                        end
                    else
                        cla(app.varmap); app.varmap.Visible='On';
                        app.varmap.Title.String = 'Var. Data Plot';
                    end
                % Compare Cells Tab    
                case 'compare cells'
                                        
                    [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);      

                    if length(cellSelInd) > 3
                        cellSelInd = cellSelInd(1:3);
                        warning('scaNpix::MainGUI::Can only compare up to 3 cells in GUI. If you want to compare more use the ''Plot CG selection'' push button!');
                    end
                    
                    plottedCGs = nchoosek(1:3,2);
                    for i = 1:3
                        if i <= length(cellSelInd)
                            plotCG(app,cellSelInd(i),app.CC_binSzEditField.Value,app.CC_lagEditField.Value,app.(['ac' num2str(i)]));
                            if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd})
                                plotWave(app,cellSelInd(i),app.(['wf' num2str(i)]));
                                tempMean = squeeze(nanmean(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd}{cellSelInd(i)},1));
                                [~, maxInd] = max(abs(min(tempMean,[],1) - max(tempMean,[],1)));
                                app.(['CC_maxChanCell' num2str(i)]).Value = ['maxChan ' num2str(maxInd)];
                            end
                            app.(['CC_cell_ID' num2str(i)]).Value                = app.CellsinDatasetListBox.Items{cellSelInd(i)};
                            app.(['CC_cell_ID' num2str(i) '_' num2str(i)]).Value = app.CellsinDatasetListBox.Items{cellSelInd(i)};
                        else
                            cla(app.(['wf' num2str(i)])); app.(['wf' num2str(i)]).Visible='On'; 
                            cla(app.(['ac' num2str(i)])); app.(['ac' num2str(i)]).Visible='On';   
                            app.(['CC_cell_ID' num2str(i)]).Value = '';
                            app.(['CC_cell_ID' num2str(i) '_' num2str(i)]).Value = '';
                            app.(['CC_maxChanCell' num2str(i)]).Value = '';
                        end
                            
                        for j = i+1:length(cellSelInd)
                            plotCG(app,cellSelInd([i j]),app.CC_binSzEditField.Value,app.CC_lagEditField.Value,app.(['cg' num2str(i) '_' num2str(j)]));
                            plottedCGs(i,:) = nan;
                        end  
                    end
                    % delete plots 
                    if length(cellSelInd) < 3
                        plottedCGs(isnan(plottedCGs(:,1)),:) = [];
                        for i = 1:size(plottedCGs,1)
                            cla(app.(['cg' num2str(plottedCGs(i,1)) '_' num2str(plottedCGs(i,2))])); app.(['cg' num2str(plottedCGs(i,1)) '_' num2str(plottedCGs(i,2))]).Visible='On';
                        end
                    end
                % linerarise it panel
                case 'linearise it'
                    
                    [~,mapTypeInd] = find(strcmp(app.RundirectionDropDown.Items,app.RundirectionDropDown.Value));
                    
                    % can we use auto select right track type from trial
                    % type property? prob needs some clever regexp
%                     trialdx = find(strcmpi(app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).trialType,app.TrialDropDown.Items));
%                     if any(trialdx) 
%                         app.TrialDropDown.Value = app.TrialDropDown.Items{trialdx(1)};
%                     end
                    if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).trackLength) 
                        app.TrackLengthEditField.Value = num2str(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(currTrSelInd).trackLength);
                    end
                    
                    if ~isempty(app.mainWin.UserData.dataObj{currDsSelInd}.posData.linXY{currTrSelInd})
                        
                        app.Lin_trialID.Value = app.TrialsinDatasetListBox.Value{1};
                        app.Lin_cellID.Value  = app.CellsinDatasetListBox.Value{1};
                        % plot all cells plot just once
                        scanpix.plot.plotLinMaps( app.mainWin.UserData.dataObj{currDsSelInd}.linMaps.linRateNormed{currTrSelInd}{mapTypeInd,1}, app.lin_allCells, 'type','cellpos' );
                        % plot current cell data
                        scanpix.plot.plotLinMaps( app.mainWin.UserData.dataObj{currDsSelInd}.linMaps.linRate{currTrSelInd}{mapTypeInd}(currCeSelInd,:), app.lin_rate );
                        % plot spike by pos
                        pos      = [app.mainWin.UserData.dataObj{currDsSelInd}.posData.linXY{currTrSelInd}(:,mapTypeInd),(1:length(app.mainWin.UserData.dataObj{currDsSelInd}.posData.linXY{currTrSelInd}))'];
                        scanpix.plot.plotSpikeByPos(pos,app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_Times{currTrSelInd}{currCeSelInd},app.lin_spikebypos,'srate',app.mainWin.UserData.dataObj{currDsSelInd}.params('posFs'),'msize',4,'axvis',true);
                        get(app.lin_spikebypos,'ylim');
                        set(app.lin_spikebypos,'ytick',[0 max(get(app.lin_spikebypos,'ylim'))],'YTickLabel',[0 app.mainWin.UserData.dataObj{currDsSelInd}.trialMetaData(currTrSelInd).duration]);
                    else
                        % clear 
                        cla(app.lin_allCells);   app.lin_allCells.Visible   = 'On';
                        cla(app.lin_rate);       app.lin_rate.Visible       = 'On';
                        cla(app.lin_spikebypos); app.lin_spikebypos.Visible = 'On';
                        app.TrackLengthEditField.Value = '';
                        app.Lin_trialID.Value          = '';
                        app.Lin_cellID.Value           = '';
                    end
            end
            
            figure(app.mainWin);
        end
        
        function setDefaults(app,type,field,value)
            
            if nargin == 2; [field, value] = scanpix.GUI.grabDefaults_mainGUI(type); end
        
            for i = 1:length(field)
                switch lower(type)
                    case 'gui'
                        app.mainWin.UserData.GUIDefaults.(field{i}) = value{i};
                    case 'fig'
                        app.mainWin.UserData.figDefaults.(field{i}) = value{i};
                    case 'maps'
                        app.mainWin.UserData.figDefaults.mapParams.(field{i}) = value{i};
                end 
            end
        end
        
        function plotRMap(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}); cla(app.rmap); app.rmap.Visible='On'; return; end
            % plot
            scanpix.plot.plotRateMap(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind},app.rmap,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps)
            axis(app.rmap,'square');
            mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind});
            set(app.rmap,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)]);
            set(get(app.rmap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{tr_ind}{ce_ind})),'%.1f')],'Visible','On');
        end
        
        function plotDMap(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}); cla(app.dmap); app.dmap.Visible='On';  return; end
            
            map = app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}{ce_ind};
            
            scanpix.plot.plotDirMap(map,app.dmap);
            set(get(app.dmap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{tr_ind}{ce_ind})),'%.1f')],'Visible','On');
        end
        
        function plotSpkPos(app)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            ce_ind   = app.mainWin.UserData.currSelInd(3);
            pos      = app.mainWin.UserData.dataObj{ds_ind}.posData.XY{tr_ind};
            spkTimes = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}{ce_ind};
            
            scanpix.plot.plotSpikeByPos(pos,spkTimes,app.spikepos,'srate',app.mainWin.UserData.dataObj{ds_ind}.params('posFs'),'msize',3.5);
            axis(app.spikepos,'square');
            set(app.spikepos,'xlim',[min(pos(:,1)) max(pos(:,1))],'ylim',[min(pos(:,2)) max(pos(:,2))]);
            set(get(app.spikepos,'xlabel'),'String',' ','Visible','On');
        end
        
        function plotWave(app,cellInd,hAx)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}); cla(app.wfs); app.wfs.Visible='On'; return; end
           
            scanpix.plot.plotWaveform(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}{cellInd,1}, hAx,'maxWaves',app.mainWin.UserData.figDefaults.nWaveForms);

            if strcmp(app.mainWin.UserData.dataType,'scanpix.dacq')
                tetN    = app.mainWin.UserData.dataObj{ds_ind}.cell_ID(cellInd,2);
                meanWFs = squeeze(nanmean(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}{cellInd,1},1));
                [~, maxChanInd] = max(abs(min(meanWFs,[],1) - max(meanWFs,[],1)));
                if ~isempty(maxChanInd)
                    scaleChan = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).ADC_fullscale_mv / app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).gains(tetN,maxChanInd) * 1e3;
                    set(app.wfs,'ylim',[-scaleChan scaleChan],'yTick',[-scaleChan scaleChan],'YtickLabel',round([-scaleChan scaleChan]));
                end
            else
                %% NPIX GOES HERE %%%
            end
        end
        
        function plotAllTrials(app)
        
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            ce_ind   = app.mainWin.UserData.currSelInd(3);
            % no maps, no plots
            if ~app.PanelAllTrials.UserData.plot || (isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}) && isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1}) )
                delete(app.PanelAllTrials.Children);
                return;
            end
            % in case of multiple datasets we need to account for different n of trials
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}) || isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                if length(app.PanelAllTrials.Children) > length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                    delete(app.PanelAllTrials.Children);
                end
            else
                if length(app.PanelAllTrials.Children)/2 > length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                    delete(app.PanelAllTrials.Children);
                end
            end

            currPos = [5,125,120,120]; %currPos = currPosSt;
            if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}{1});
            end
            nMapStyle = ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1}) + ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1});
            axCheck = length(app.PanelAllTrials.Children) < nMapStyle * length(app.mainWin.UserData.dataObj{ds_ind}.trialNames);
            if app.mainWin.UserData.figDefaults.normColMapByMax
               maxVal = nanmax(cellfun(@(x) nanmax(x(:)),cellfun(@(x) x{ce_ind},app.mainWin.UserData.dataObj{ds_ind}.maps.rate,'uni',0)));
            end
            for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)

                if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                    % check if axis exist or not
                    if axCheck && ( isempty(app.PanelAllTrials.Children) || ~any([app.PanelAllTrials.Children(:).UserData] == 2*i-1) )
                        hAx = uiaxes(app.PanelAllTrials,'position',currPos);
                        % we need to tag axes here to keep track of order
                        % later
                        app.PanelAllTrials.Children(1).UserData = 2*i-1;  % Axes in Children are appended to top of the list!
                    else
                        ind = [app.PanelAllTrials.Children(:).UserData] == 2*i-1;
                        hAx = app.PanelAllTrials.Children(ind); 
                    end
                    if app.mainWin.UserData.figDefaults.normColMapByMax
                        scanpix.plot.plotRateMap(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}{ce_ind},hAx,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps,'binVals',[0 maxVal]);
                    else
                        scanpix.plot.plotRateMap(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}{ce_ind},hAx,'colmap',app.mainWin.UserData.figDefaults.rMapColMap,'nsteps',app.mainWin.UserData.figDefaults.rMapColNSteps);
                    end
                    % format axis
                    axis(hAx,'square');
                    set(hAx,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)]);
                    set(get(hAx,'xlabel'),'String',['peakFR=' num2str(nanmax(nanmax(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}{ce_ind})),'%.1f')],'Visible','On','Verticalalignment','bottom');
                    title(hAx,app.mainWin.UserData.dataObj{ds_ind}.trialNames{i},'Interpreter','none');
                end
                
                if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                    if axCheck && ( isempty(app.PanelAllTrials.Children) || ~any([app.PanelAllTrials.Children(:).UserData] == 2*i) )
                        hAx = uiaxes(app.PanelAllTrials,'position',currPos - [0 120 0 0]);
                        app.PanelAllTrials.Children(1).UserData = 2*i;
                    else
                        ind =[app.PanelAllTrials.Children(:).UserData] == 2*i;
                        hAx = app.PanelAllTrials.Children(ind); 
                    end
                    scanpix.plot.plotDirMap(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i}{ce_ind}, hAx);
                    set(get(hAx,'xlabel'),'String',['peakFR=' num2str(nanmax(nanmax(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i}{ce_ind})),'%.1f')],'Visible','On','Verticalalignment','bottom');
                end
                currPos(1) = sum(currPos([1 3]));
            end
        end
        
        function str = freezePlotsSel(app,mode,varargin)
            
            axisNames  = {'wfs',      'ac_shortLag', 'ac_longLag', 'spikepos',    'rmap',    'varmap',        'dmap',   'PanelAllTrials'};
            defaultStr = {'Waveforms','AC short Lag','AC long lag','Spike by Pos','Rate Map','Var. Data Plot','Dir Map','All Trials Panel'};
            switch mode
                case 'get'
                    ind = false(1,length(defaultStr));
                    for i = 1:length(defaultStr)
                        ind(i) = app.(axisNames{i}).UserData.plot;
                    end
                    if varargin{1}
                        str = defaultStr(ind);
                    else
                        str = defaultStr(~ind);
                    end
                case 'set'
                    for i = 1:length(varargin{1})
                        ind = strcmp(varargin{1}{i},defaultStr);
                        app.(axisNames{ind}).UserData.plot = ~app.(axisNames{ind}).UserData.plot;
                    end
                    str = {};
                case 'init'
                    for i = 1:length(axisNames)
                        app.(axisNames{i}).UserData.plot = true;
                    end 
            end   
        end
        
        function plot_sACs(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}); cla(app.varmap); app.varmap.Visible='On'; title(app.varmap,'Var. Data Plot'); return; end
            % plot
            imagesc(app.varmap,app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind}); colormap(app.varmap,app.mainWin.UserData.figDefaults.sACColMap);
            axis(app.varmap,'square');
            title(app.varmap,'Spatial AC');
            mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{tr_ind}{ce_ind});
            set(app.varmap,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)]);
            set(get(app.varmap,'xlabel'),'String',' ','Visible','On');
        end
        
        function makeSACs(app)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            if isempty( app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                warning([app.mainWin.Name '-GUI:MakeSACs:You need to make rate maps first before you can generate spatial ACs.']);
                return;
            end
            for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{i} = cellfun(@(x) scanpix.analysis.spatialCrosscorr(x,x,'smooth'), app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i},'uni',0);
            end
        end
             
        function plotCG(app,cellInd,binSz,lag,hAx)
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            
            if length(cellInd) == 1
                stB = 'AC';
            else
                stB = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}{cellInd(2)};
            end
            ac = scanpix.analysis.spk_crosscorr(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}{cellInd(1)},...
                     stB,binSz,lag,app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).duration,'plot',hAx);
            
            if all(isnan(ac))
                maxY = 1;
            else
                maxY = 1.1*max(ac);
            end
            
%             if isempty(hAx.Children.XData)
%                 shading(hAx,'FLAT');
%                 hBar=bar(hAx,xVect,xc,'histc');
%                 set(hBar, 'edgecolor', 'none', 'facecolor', [1 1 1].*0.5);
%             else
%                 hAx.Children.YData(2:3,:) = [ac ac]'; 
%             end
            
            axis(hAx,'square');
            set(hAx,'xlim',[-lag lag],'xtick',[-lag 0 lag],'xticklabel',[-lag*1000 0 lag*1000],'ylim',[0 maxY]);
            
            if length(cellInd) == 1
                title(hAx,['AC ' num2str(lag*1000) 'ms']);
            else
                title(hAx,['CG ' num2str(lag*1000) 'ms']);
            end
        end
        
        function deletePrompt(app,~,event)
            if strcmpi(event.SelectedOption,'OK')
                delete(app);
            end
        end
        
        function makeOVmaps(app)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            
%             if isempty( app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
%                 warning([app.mainWin.Name '-GUI:MakeSACs:You need to make rate maps first before you can generate spatial ACs.']);
%                 return;
%             end
            scanpix.maps.addMaps(app.mainWin.UserData.dataObj{ds_ind},'objVect',[],app.mainWin.UserData.figDefaults.mapParams);
        end
        
        function plot_OVmaps(app)
            ds_ind = app.mainWin.UserData.currSelInd(1);
            tr_ind = app.mainWin.UserData.currSelInd(2);
            ce_ind = app.mainWin.UserData.currSelInd(3);
            
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).objectPos); cla(app.varmap); app.varmap.Visible='On'; title(app.varmap,'Var. Data Plot'); return; end
            % plot
            imagesc(app.varmap,app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind}); 
            caxis(app.varmap,[0 nanmax(nanmax(app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind}))]); 
            colormap(app.varmap,app.mainWin.UserData.figDefaults.OVcolMap);
            axis(app.varmap,'square');
            title(app.varmap,'Object Vect');
            mapSz = size(app.mainWin.UserData.dataObj{ds_ind}.maps.OV{tr_ind}{ce_ind});
            set(app.varmap,'ydir','normal','xlim',[0 mapSz(2)],'xtick',[0 mapSz(2)/2 mapSz(2)],'ylim',[0 mapSz(1)],'ytick',[0 mapSz(1)/4 mapSz(1)/2 mapSz(1)*0.75 mapSz(1)],'yticklabel',{'0','\pi/2','\pi','1.5\pi','2\pi'});
            set(get(app.varmap,'xlabel'),'String','Distance','Visible','On');
            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dataType)
            
            setDefaults(app,'gui');
            setDefaults(app,'fig');
            setDefaults(app,'maps');
            % set a few things programmatically
            app.MakeRMapsButton.Tag        = 'rate';
            app.MakeDMapsButton.Tag        = 'dir';
            app.TrialsinDatasetListBox.Tag = 'TrialSelBox';
            app.SelectDatasetsListBox.Tag  = 'DSSelBox';
            app.CC_binSzEditField.Value    = app.mainWin.UserData.figDefaults.ac_bSz_shortLag;
            app.CC_lagEditField.Value      = app.mainWin.UserData.figDefaults.ac_lag_shortLag;
            freezePlotsSel(app,'init');
%             baseSz = [100 100 1220 760];
%             screenSize = get(0,'ScreenSize');
%             drawnow;
%             app.mainWin.Position = [0.1*screenSize(1:2) 0.85*screenSize(3) 0.8*screenSize(4)];
            app.mainWin.UserData.dataObj = {};
            
            if nargin == 1 || isempty(dataType)
                str = {'dacq','npix'};
                [select, loadCheck] = listdlg('PromptString','Select what type of Data You Want to Browse:','ListString',str,'ListSize',[100 50],'SelectionMode','Single');
                if ~loadCheck
                    delete(app);
                    return;
                end
                str = str{select};
            else
                str = dataType;
            end
            app.mainWin.Name =  ['scaNpix.' str];
            app.mainWin.UserData.dataType = ['scanpix.', str];
            
            if strcmp(str,'npix')
                app.PlotClusterSpaceButton.Enable = 'off'; % this is dacq only atm
                app.LoadWaveForms.Visible = 'On';
                app.LoadWaveForms.Enable  = 'On';
            end
            % this is npix only
            if strcmp(str,'dacq')
                app.ByClustLabelMenu.Enable  = 'off'; 
%                 app.ByClustLabelMenu.Visible = 'off'; 
            end
            
            baseVarInfo = evalin('base', 'whos');
            ind = strcmp({baseVarInfo(:).class}, app.mainWin.UserData.dataType);
            varNames = {baseVarInfo(ind).name};
            
            if isempty(varNames)                
                figure(app.mainWin);
                return;
            end
            % select what to import
            [select, loadCheck] = listdlg('PromptString','Select Which Obj from Workspace You Want to Import:','ListString',varNames,'ListSize',[150 50]);
            if ~loadCheck
                figure(app.mainWin);
                return;
            end
            varNames = varNames(select);
            % assign selected data to obj
            for i = 1:length(varNames)
                tempObj =  evalin('base', varNames{i});
                
                app.mainWin.UserData.dataObj{i}    = tempObj;
                app.SelectDatasetsListBox.Items{i} = tempObj.dataSetName;
                
                switch app.mainWin.UserData.dataType
                    case 'scanpix.dacq'
                        app.mainWin.UserData.cellIDStr{i} = strrep(string(strcat('c',cellstr(num2str(tempObj.cell_ID(:,1))),'t',cellstr(num2str(tempObj.cell_ID(:,2))))),' ','');
                    case 'scanpix.npix'
                        app.mainWin.UserData.cellIDStr{i} = strrep(string(strcat('clu_',num2str(tempObj.cell_ID(:,1)))),' ','');
                end
                
                app.mainWin.UserData.Filter{i} = true(length(app.mainWin.UserData.cellIDStr{i}),1);
                
            end
            app.mainWin.UserData.currSelInd    = [1 1 1];  
            
            setFieldsGUI(app,[]);
        end

        % Callback function: LoadDataButton, LoadfromRawMenu
        function LoadDataButtonPushed(app, event)
            if isempty(app.mainWin.UserData.dataObj)
                assignInd = 1;
            else
                assignInd = length(app.mainWin.UserData.dataObj) + 1;
            end
            
            switch app.mainWin.UserData.dataType
                case 'scanpix.dacq'
                    tempObj = scanpix.dacq('ui');
                    if isempty(tempObj.trialNames); return; end
                    tempObj.load({'pos','spikes'});
                    if ~tempObj.loadFlag || isempty(tempObj.spikeData.spk_Times{1}); figure(app.mainWin); return; end
                    app.mainWin.UserData.cellIDStr{assignInd} = strcat('c',strip(cellstr(num2str(tempObj.cell_ID(:,1)))),'t',strip(cellstr(num2str(tempObj.cell_ID(:,2))))); 
                case 'scanpix.npix'
                    tempObj = scanpix.npix('ui');
                    if isempty(tempObj.trialNames); return; end
                    tempObj.load({'pos','spikes'});
                    if ~tempObj.loadFlag || isempty(tempObj.spikeData.spk_Times{1}); figure(app.mainWin); return; end
                    app.mainWin.UserData.cellIDStr{assignInd} = strrep(string(strcat('clu_',num2str(tempObj.cell_ID(:,1)))),' ','');
                    app.LoadWaveForms.Visible = 'On';
                    app.LoadWaveForms.Enable  = 'On';
            end
            
            app.mainWin.UserData.dataObj{assignInd}    = tempObj;

            % make sure name is unique
            ds_name = tempObj.dataSetName;
            c = 2;
            while any(strcmp(app.SelectDatasetsListBox.Items,ds_name))
                ds_name = [ds_name '_' num2str(c)]; %#okagrow
                c = c+1;
            end
            app.SelectDatasetsListBox.Items{assignInd}          = ds_name;
            app.mainWin.UserData.dataObj{assignInd}.dataSetName = ds_name;
            app.mainWin.UserData.currSelInd                     = [length(app.mainWin.UserData.dataObj) 1 1];
            app.mainWin.UserData.Filter{assignInd}              = true(length(app.mainWin.UserData.cellIDStr{assignInd}),1);
            
            setFieldsGUI(app, event);
        end

        % Menu selected function: LoadfromGUIstateMenu
        function LoadfromGUIstateMenuSelected(app, event)
            [fname, path] = uigetfile(fullfile(app.mainWin.UserData.GUIDefaults.outputDir,['GUIState_' app.mainWin.UserData.dataType(9:end) '*.mat']),'Select GUI State Data');
            if ~ischar (fname); return; end
            tmp = load(fullfile(path,fname));
            
            if isempty(app.mainWin.UserData.dataObj)
                assignInd = 1;
                app.mainWin.UserData.GUIDefaults = tmp.GUIDefaults;
            else
                assignInd = length(app.mainWin.UserData.dataObj) + 1;
%                 app.mainWin.UserData.dataObj{assignInd:assignInd+length(tmp.objData)-1} = tmp.objData;
            end
            
            app.mainWin.UserData.dataObj(assignInd:assignInd+length(tmp.objData)-1)    = tmp.objData(:);
            app.mainWin.UserData.cellIDStr(assignInd:assignInd+length(tmp.objData)-1)  = tmp.cellIDStr(:);
            app.SelectDatasetsListBox.Items(assignInd:assignInd+length(tmp.objData)-1) = cellfun(@(x) x.dataSetName,tmp.objData,'uni',0);
            app.mainWin.UserData.Filter(assignInd:assignInd+length(tmp.objData)-1)     = cellfun(@(x) true(length(x),1),tmp.cellIDStr,'uni',0);

            app.mainWin.UserData.currSelInd = [assignInd 1 1];
            %%% FILTER???
%             app.mainWin.UserData.dataObj{assignInd}.dataSetName = ds_name;
            setFieldsGUI(app, event);
        end

        % Value changed function: SelectDatasetsListBox
        function SelectDatasetsListBoxValueChanged(app, event)
            [~, ind] = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
            app.mainWin.UserData.currSelInd = [ind(1) 1 1];
            setFieldsGUI(app, event);    
        end

        % Button pushed function: SelectAllDatasets
        function SelectAllDatasetsButtonPushed(app, event)
            app.SelectDatasetsListBox.Value = app.SelectDatasetsListBox.Items;
        end

        % Value changed function: TrialsinDatasetListBox
        function TrialsinDatasetListBoxValueChanged(app, event)
            [~, ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            app.mainWin.UserData.currSelInd(2) = ind(1);
            setFieldsGUI(app, event);   
        end

        % Button pushed function: SelectAllTrials
        function SelectAllTrialsPushed(app, event)
            app.TrialsinDatasetListBox.Value = app.TrialsinDatasetListBox.Items;
        end

        % Value changed function: CellsinDatasetListBox
        function CellsinDatasetListBoxValueChanged(app, event)
            ds_ind = app.mainWin.UserData.currSelInd(1);
%             [~, ind] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            [~, ind] = ismember(app.CellsinDatasetListBox.Value, app.mainWin.UserData.cellIDStr{ds_ind});
            app.mainWin.UserData.currSelInd(3) = ind(1);
            setFieldsGUI(app, event);   
        end

        % Button pushed function: SelectAllCells
        function SelectAllCellsPushed(app, event)
            app.CellsinDatasetListBox.Value = app.CellsinDatasetListBox.Items;
        end

        % Button pushed function: MakeDMapsButton, MakeRMapsButton
        function MakeRMapsButtonPushed(app, event)

        ds_ind = app.mainWin.UserData.currSelInd(1);
            if strcmp(event.Source.Tag,'rate')
                mapType = 'rate';
            elseif strcmp(event.Source.Tag,'dir')
                mapType = 'dir';
            end
            scanpix.maps.addMaps(app.mainWin.UserData.dataObj{ds_ind},mapType,[],app.mainWin.UserData.figDefaults.mapParams);
            setFieldsGUI(app,event);
        end

        % Value changed function: varMapList
        function varMapListValueChanged(app, event)
            setFieldsGUI(app,event); 
        end

        % Button pushed function: varmapButton
        function varmapButtonPushed(app, event)
            switch lower(app.varMapList.Value)
                case 'spatial ac'
                    makeSACs(app);
                case 'object vect'
                    makeOVmaps(app);
                case 'speed mod.'
                    
            end
            
            setFieldsGUI(app,event);   
        end

        % Button pushed function: LoadWaveForms
        function LoadWaveFormsPushed(app, event)
            
            ds_ind = app.mainWin.UserData.currSelInd(1);
            app.mainWin.UserData.dataObj{ds_ind}.loadWaves;
            
            setFieldsGUI(app,event);       
        end

        % Button pushed function: PlotSelectionButton
        function PlotSelectionButtonPushed(app, event)
            %% ADD LIN RATE MAPS AS OPTION?
            mapType = {'rate','spike','pos','dir','sACs'};
            [select, loadCheck] = listdlg('PromptString','Select what type of Maps You Fancy:','ListString',mapType,'ListSize',[150 75]);
            if ~loadCheck
                return;
            end
            mapType = mapType(select);
            
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            [~, tr_ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            [~, ce_ind] = ismember(app.CellsinDatasetListBox.Value, app.mainWin.UserData.cellIDStr{ds_ind});
            
            for i = 1:length(mapType)
                if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.(mapType{i})); continue; end
                maps = cellfun(@(x) x(ce_ind),app.mainWin.UserData.dataObj{ds_ind}.maps.(mapType{i})(tr_ind),'uni',0);
                scanpix.plot.plotMapsGroup(maps,mapType{i},app.mainWin.UserData.cellIDStr{ds_ind}(ce_ind),'figname',[app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_' mapType{i} 'MapGroup']);
            end
        end

        % Menu selected function: FreezeMenu
        function FreezeMenuSelected(app, event)
            str = freezePlotsSel(app,'get',true);
            if isempty(str); warning([app.mainWin.Name '-GUI:FreezeMenu:Updates for all plots are already frozen mate.']); return; end
            
            [select, loadCheck] = listdlg('PromptString','Select Which Plots You Want to Freeze:','ListString',str,'ListSize',[200 200],'OKString','Freeze');
            if loadCheck
                freezePlotsSel(app,'set',str(select));
            end
            figure(app.mainWin);
            
        end

        % Menu selected function: UnFreezeMenu
        function UnFreezeMenuSelected(app, event)
            str = freezePlotsSel(app,'get',false);
            if isempty(str); warning([app.mainWin.Name '-GUI:UnFreezeMenu:No plots to Unfreeze here pal.']); return; end

            [select, loadCheck] = listdlg('PromptString','Select Which Plots You Want to UnFreeze:','ListString',str,'ListSize',[200 200],'OKString','UnFreeze');
            if loadCheck
                freezePlotsSel(app,'set',str(select));
            end
            figure(app.mainWin);
            
        end

        % Menu selected function: GUIMenu
        function GUIMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.GUIDefaults);
            defVals  = struct2cell(app.mainWin.UserData.GUIDefaults);
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            setDefaults(app,'gui',rtn(:,1),rtn(:,2));
        end

        % Menu selected function: GeneralPlottingMenu
        function GeneralPlottingMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.figDefaults);
            defVals = struct2cell(app.mainWin.UserData.figDefaults);
            ind     = strcmp(prompts,'mapParams');
            prompts = prompts(~ind);
            defVals = defVals(~ind);
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            setDefaults(app,'fig',rtn(:,1),rtn(:,2));
            
            setFieldsGUI(app,event);
        end

        % Menu selected function: RateMapsMenu
        function RateMapsMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.figDefaults.mapParams);
            defVals = struct2cell(app.mainWin.UserData.figDefaults.mapParams);
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            setDefaults(app,'maps',rtn(:,1),rtn(:,2));
        end

        % Menu selected function: DeleteDatasetsMenu
        function DeleteDatasetsMenuSelected(app, event)
            [~, ind] = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
            selInd = true(1,length(app.SelectDatasetsListBox.Items));
            selInd(ind) = false;
            if any(selInd)
                app.mainWin.UserData.dataObj    = app.mainWin.UserData.dataObj(selInd);
                app.SelectDatasetsListBox.Items = app.SelectDatasetsListBox.Items(selInd);
                
                app.mainWin.UserData.currSelInd = [1 1 1];
                setFieldsGUI(app,event);
            else
                [app.mainWin.UserData.dataObj,app.SelectDatasetsListBox.Items,app.TrialsinDatasetListBox.Items,app.CellsinDatasetListBox.Items] = deal({});
                [app.CurrentDatasetEditField.Value,app.TrialEditField.Value,app.CellEditField.Value] = deal('');                      
            end
        end

        % Menu selected function: DeleteTrialsMenu
        function DeleteTrialsMenuSelected(app, event)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            [~, ind] = ismember(app.TrialsinDatasetListBox.Value, app.TrialsinDatasetListBox.Items);
            delInd = false(1,length(app.TrialsinDatasetListBox.Items));
            delInd(ind) = true;
            
            if ~all(delInd)
                scanpix.helpers.deleteData(app.mainWin.UserData.dataObj{ds_ind}, app.mainWin.UserData.dataObj{ds_ind}.trialNames(delInd)); 
                app.mainWin.UserData.currSelInd(2:3) = [1 1];
                setFieldsGUI(app,event);
            else
                hgfeval({@DeleteDatasetsMenuSelected,app,event}); % delete dataset in case all trials were deleted 
            end
            
        end

        % Menu selected function: DeleteCellsMenu
        function DeleteCellsMenuSelected(app, event)
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            [~, ind] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            selInd = true(1,length(app.CellsinDatasetListBox.Items));
            selInd(ind) = false;
            
            if any(selInd)
                f = fieldnames(app.mainWin.UserData.dataObj{ds_ind}.spikeData);
                for i = 1:length(f)
                    app.mainWin.UserData.dataObj{ds_ind}.spikeData.(f{i}) = cellfun(@(x) x(selInd),app.mainWin.UserData.dataObj{ds_ind}.spikeData.(f{i}),'uni',0);
                end
                f = fieldnames(app.mainWin.UserData.dataObj{ds_ind}.maps);
                for i = 1:length(f)
                    if ~isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.(f{i}){1})
                        app.mainWin.UserData.dataObj{ds_ind}.maps.(f{i}) = cellfun(@(x) x(selInd),app.mainWin.UserData.dataObj{ds_ind}.maps.(f{i}),'uni',0);
                    end
                end
                app.mainWin.UserData.dataObj{ds_ind}.cell_ID = app.mainWin.UserData.dataObj{ds_ind}.cell_ID(selInd,:);
                if strcmp(app.mainWin.UserData.dataType,'scanpix.npix')
                    app.mainWin.UserData.dataObj{ds_ind}.cell_Label = app.mainWin.UserData.dataObj{ds_ind}.cell_Label(selInd,:);
                end
                app.mainWin.UserData.cellIDStr{ds_ind} = app.mainWin.UserData.cellIDStr{ds_ind}(selInd,:);
                
                app.mainWin.UserData.currSelInd(3) = 1;
                setFieldsGUI(app,event);
            else
                hgfeval({@DeleteDatasetsMenuSelected,app,event}); % delete dataset in case all cells were deleted 
            end
        end

        % Value changed function: CurrentDatasetEditField
        function CurrentDatasetEditFieldValueChanged2(app, event)
            [~, ind]                                            = ismember(app.SelectDatasetsListBox.Items, event.PreviousValue);
            app.SelectDatasetsListBox.Items{find(ind)}          = app.CurrentDatasetEditField.Value;
            app.mainWin.UserData.dataObj{find(ind)}.dataSetName = app.CurrentDatasetEditField.Value;  
        end

        % Menu selected function: plotWaveformMenu
        function plotWaveformMenuSelected(app, event)
            
            currDsSelInd = app.mainWin.UserData.currSelInd(1);
            currTrSelInd = app.mainWin.UserData.currSelInd(2);
            currCeSelInd = app.mainWin.UserData.currSelInd(3);
                        
            if isempty(app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{1})
                warning('scaNPix::MainGUI::You need to load waveforms first before plotting them.... kinda obvious, isn''t it?');
                return;
            else
                % quite a bit of hard coded pixel values here
                figSz  = [170 475];
                plotSz = [50 150];
                screenSz = get(0,'ScreenSize');
                hFig = figure('Name',['WaveDist_clu_' num2str(app.mainWin.UserData.dataObj{currDsSelInd}.cell_ID(currCeSelInd,1))],'Units','pixels','Position',[0.5*screenSz(3)-figSz(1)/2 0.5*screenSz(4)-figSz(2)/2 figSz]);
                
                idx    = ismember(app.mainWin.UserData.dataObj{currDsSelInd}.chanMap.chanMap,app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd,2}{currCeSelInd});
                coords = [app.mainWin.UserData.dataObj{currDsSelInd}.chanMap.xcoords(idx) app.mainWin.UserData.dataObj{currDsSelInd}.chanMap.ycoords(idx)];
                shifts = [diff(coords,[],1);0 0];
                
                currWave = app.mainWin.UserData.dataObj{currDsSelInd}.spikeData.spk_waveforms{currTrSelInd,1}{currCeSelInd};
                
                xOffSet = [11 27 43 59; 15 45 75 105];
                yOffSet = figSz(2)-plotSz(2);
                
                faceAlpha = .3;
                for i = 1:size(coords,1)
                    tempAx = axes(hFig,'units','pixel','Position',[xOffSet(2,xOffSet(1,:)==coords(i,1)) yOffSet plotSz]);
                    scanpix.plot.plotWaveform(-currWave(:,:,i),tempAx,'plotIndWFs',false);
                    % plot a square to indicate electrode pos on shank - not really necessary but makes figure clearer
                    tempAx2 = axes(hFig,'units','pixel','Position',[xOffSet(2,xOffSet(1,:)==coords(i,1)) yOffSet+35 plotSz(1) 60]);
      
                    patch(tempAx2,[0 1 1 0],[0 0 1 1],[0.3 0.3 0.3],'FaceAlpha',faceAlpha);
                    axis(tempAx2,'square');
                    axis(tempAx2,'off');
%                     text(tempAx)
                    axis(tempAx,'off');
                   
                    if coords(i,1) > 30
                        if faceAlpha == .3; faceAlpha = .1; else; faceAlpha = .3; end
                    end

                    yOffSet = yOffSet - shifts(i,2)*3;
                end
            end
        end

        % Button pushed function: PlotCGSelectionButton
        function PlotCGSelectionButtonPushed(app, event)
            % relevant indices
            ds_ind          = app.mainWin.UserData.currSelInd(1);
            tr_ind          = app.mainWin.UserData.currSelInd(2);
            [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);  
            % select data
            spikeTimes = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_Times{tr_ind}(cellSelInd,1);
            cellIDs    = app.CellsinDatasetListBox.Value;
            % plot
            scanpix.plot.plotCGsGroup(spikeTimes,app.CC_binSzEditField.Value,app.CC_lagEditField.Value,app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).duration,cellIDs);
            
        end

        % Button pushed function: PlotWFSelectionButton
        function PlotWFSelectionButtonPushed(app, event)
            % relevant indices
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            if isempty(app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind})
                warning('scaNpix::MainGUI::PlotWFSelection:You need to load waveforms first, before demanding a plot.');
                return
            end
            [~, cellSelInd] = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);  
            % select data
            waveforms = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}(cellSelInd,1);
            cellIDs = app.CellsinDatasetListBox.Value;    
            scanpix.plot.plotWFsGroup(waveforms,cellIDs);
        end

        % Button pushed function: PlotClusterSpaceButton
        function PlotClusterSpaceButtonPushed(app, event)
            % relevant indices
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);
            % select data
            if strcmp(app.mainWin.UserData.dataType,'scanpix.dacq')
                tetrodes = unique(app.mainWin.UserData.dataObj{ds_ind}.cell_ID(:,2));
            else
                warning('scaNpix::MainGUI::PlotClusterSpace:Not yet supported for neuropixel data');
                return;
            end
            [select, loadCheck] = listdlg('PromptString','Select Tetrode For Plotting The Cluster Space:','ListString',strcat({'Tetrode_'},num2str(tetrodes)),'ListSize',[160 100]);
            if ~loadCheck
                warning('scaNpix::MainGUI::Aborted plotting the cluster space. Very disappointing.');
                return;
            else
                tetNum = tetrodes(select);
            end
            cellSelInd = app.mainWin.UserData.dataObj{ds_ind}.cell_ID(:,2) == tetNum;
            waveforms = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind}(cellSelInd,1);
            scaleMax = app.mainWin.UserData.dataObj{ds_ind}.trialMetaData(tr_ind).fullscale(tetNum,:);
            cellIDs = app.CellsinDatasetListBox.Items(cellSelInd);    
            scanpix.plot.plotCluSpace(waveforms,scaleMax,cellIDs);  
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            setFieldsGUI(app,event);            
        end

        % Close request function: mainWin
        function mainWinCloseRequest(app, event)
            uiconfirm(app.mainWin,'Close This Amazing GUI? Are you for real...','Close scaNpix GUI','CloseFcn',@(src,event)deletePrompt(app,src,event)); % needs 2017b
        end

        % Value changed function: CC_binSzEditField
        function CC_binSzEditFieldValueChanged(app, event)
            setFieldsGUI(app,event);
        end

        % Value changed function: CC_lagEditField
        function CC_lagEditFieldValueChanged(app, event)
            setFieldsGUI(app,event);
        end

        % Button pushed function: LineariseItButton
        function LineariseItButtonPushed(app, event)
            % gather info
            ds_ind   = app.mainWin.UserData.currSelInd(1);
            tr_ind   = app.mainWin.UserData.currSelInd(2);

            app.mainWin.UserData.dataobj{ds_ind}.trialMetaData(tr_ind).trialType   = app.TrackTypeDropDown.Value;
            app.mainWin.UserData.dataobj{ds_ind}.trialMetaData(tr_ind).trackLength = app.TrackLengthEditField.Value;
            % make maps
            scanpix.maps.addMaps(app.mainWin.UserData.dataobj{ds_ind},tr_ind,'lin',app.mainWin.UserData.figDefaults.mapParams);
        end

        % Value changed function: RundirectionDropDown
        function RundirectionDropDownValueChanged(app, event)
            setFieldsGUI(app,event);  
        end

        % Menu selected function: plotWaveDistonProbeMenu
        function plotWaveDistonProbeMenuSelected(app, event)
            
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            tr_ind    = app.mainWin.UserData.currSelInd(2);

            [~, ind]  = ismember(app.CellsinDatasetListBox.Value, app.CellsinDatasetListBox.Items);
            
            if length(ind) == 1
                warning('scaNpix::MainGUI::You need to select at least 2 clusters to plot distribution on shank. You can do better than this.');
                return;
            end
            
            % mean waveforms
            tempWaves = app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind,1}(ind);
            meanWFs = cellfun(@(x) squeeze(nanmean(x,1)),tempWaves,'uni',0);
            [~, maxInd] = cellfun(@(x) max(abs(min(x,[],1) - max(x,[],1))),meanWFs,'uni',0);
            % max chan ID
            tempCh = cell2mat(cellfun(@(x,y) x(y), app.mainWin.UserData.dataObj{ds_ind}.spikeData.spk_waveforms{tr_ind,2}(ind), maxInd,'uni',0));
            
            chanMap   = [app.mainWin.UserData.dataObj{ds_ind}.chanMap(tr_ind).xcoords app.mainWin.UserData.dataObj{ds_ind}.chanMap(tr_ind).ycoords];
            cluCoords = [app.mainWin.UserData.dataObj{ds_ind}.chanMap(tr_ind).xcoords(tempCh) app.mainWin.UserData.dataObj{ds_ind}.cell_ID(ind,2)];
            % plot
            scanpix.plot.plotWaveDistByLocOnShank(meanWFs,cluCoords,chanMap);
        end

        % Menu selected function: ByClustLabelMenu
        function ByClustLabelMenuSelected(app, event)
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            
            str = unique(app.mainWin.UserData.dataObj{ds_ind}.cell_Label);
            [select, loadCheck] = listdlg('PromptString','Select cluster label:','ListString',str,'ListSize',[160 100]);
            if ~loadCheck
                warning('scaNpix::MainGUI::Filtering aborted. What a waste...');
                return;
            else
                str = str(select);
            end
            
            app.mainWin.UserData.Filter{ds_ind} = scanpix.GUI.filterDataGUI(app.mainWin.UserData.dataObj{ds_ind}.cell_Label,str,'byLabel');
            app.CellsinDatasetListBox.Items     = app.mainWin.UserData.cellIDStr{ds_ind}(app.mainWin.UserData.Filter{ds_ind});
            app.CellsinDatasetListBox.Value     = app.CellsinDatasetListBox.Items{1};

            app.mainWin.UserData.currSelInd(3)  = 1;
             
            setFieldsGUI(app,event);
        end

        % Menu selected function: RemoveFilterMenu
        function RemoveFilterMenuSelected(app, event)
            ds_ind                              = app.mainWin.UserData.currSelInd(1);
            
            app.mainWin.UserData.Filter{ds_ind} = true(length(app.mainWin.UserData.cellIDStr{ds_ind}),1);
            app.CellsinDatasetListBox.Items     = app.mainWin.UserData.cellIDStr{ds_ind};
            app.CellsinDatasetListBox.Value     = app.CellsinDatasetListBox.Items{1};
            app.mainWin.UserData.currSelInd(3)  = 1;
            
            setFieldsGUI(app,event);
        end

        % Menu selected function: gridnessMenu, rayleighvectorMenu, 
        % spatialInfoMenu
        function filterByValue(app, event)
        
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            % gather the appropriate data for filtering
            switch event.Source.Text
                case 'spatial Info'
                    vals    = {0.4,'gt', 1};
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                        scanpix.maps.addMaps(app.mainWin.UserData.dataObj{ds_ind},'rate',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    filtVals = nan(size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1),length(app.mainWin.UserData.dataObj{ds_ind}.trialNames));
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        filtVals(:,i) = cell2mat(cellfun(@(x,y) scanpix.analysis.spatial_info(x,y),app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i},app.mainWin.UserData.dataObj{ds_ind}.maps.pos{i},'uni',0));
                    end
                case 'gridness'
                    vals    = {0.3,'gt', 1};
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{1})
                        makeSACs(app);
                    end
                    filtVals = nan(size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1),length(app.mainWin.UserData.dataObj{ds_ind}.trialNames));
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        filtVals(:,i) = cell2mat(cellfun(@(x) scanpix.analysis.gridprops(x,'peakMode','point','corrThr',0,'radius','est'),app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{i},'uni',0));
                    end
                case 'rayleigh vector'
                    vals    = {0.5,'gt', 1};
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                        scanpix.maps.addMaps(app.mainWin.UserData.dataObj{ds_ind},'dir',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    filtVals = nan(size(app.mainWin.UserData.dataObj{ds_ind}.cell_ID,1),length(app.mainWin.UserData.dataObj{ds_ind}.trialNames));
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        filtVals(:,i) = cell2mat(cellfun(@(x,y) scanpix.analysis.rayleighVect(x),app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i},'uni',0));
                    end
                otherwise
            end
            
            prompts = {'threshold';'direction';'trialIndex'};
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,vals);
            % in case more trials should be used to make filter
            if ~isnumeric(rtn{3,2})
                trialN   = sscanf(rtn{3,2},'%d%*c');
                temp     = char(sscanf(rtn{3,2},'%*d%c'));
                if strcmp(temp(1),'&')
                    opFh = @and; 
                elseif strcmp(temp(1),'|')
                    opFh = @or; 
                else
                    warning('scaNpix::MainGUI::FilterData:You can only combine trials with ''&''s'' or ''|''s'' to make a composite filter. You can do it.');
                    return;
                end
            else
                trialN = rtn{3,2};
            end
            if any(trialN > length(app.mainWin.UserData.dataObj{ds_ind}.trialNames) )
                warning('scaNpix::MainGUI::FilterData:Dataset only contains %i trials but you wanted to use trial #%i...',length(app.mainWin.UserData.dataObj{ds_ind}.trialNames),max(trialN));
                return;
            end
            % make the filter
            filtVals = filtVals(:,trialN);   
            for i = 1:length(trialN)
                if i == 1
                    tempFilt = scanpix.GUI.filterDataGUI(filtVals(:,i),rtn{1,2},'byValue',rtn{2,2});
                else
                    tempFilt = opFh(tempFilt,scanpix.GUI.filterDataGUI(filtVals(:,i),rtn{1,2},'byValue',rtn{2,2})); 
                end
            end
            % apply to data
            app.mainWin.UserData.Filter{ds_ind} = tempFilt;
            app.CellsinDatasetListBox.Items     = app.mainWin.UserData.cellIDStr{ds_ind}(tempFilt);
            app.CellsinDatasetListBox.Value     = app.CellsinDatasetListBox.Items{1};

            app.mainWin.UserData.currSelInd(3)  = 1;
            
            setFieldsGUI(app,event); 
        end

        % Callback function: ExporCurSelMenu, ExportDataButton, 
        % ExportFullMenu
        function exportData(app, event)
            if strcmp(event.Source.Text,'Current Selection')
                [~, ind]  = ismember(app.SelectDatasetsListBox.Value, app.SelectDatasetsListBox.Items);
                objData   = app.mainWin.UserData.dataObj(ind);
                cellIDStr = app.mainWin.UserData.cellIDStr(ind);
            elseif strcmp(event.Source.Text,'Full') || strcmp(event.Source.Text,'Export Data')
                objData   = app.mainWin.UserData.dataObj(:);
                cellIDStr = app.mainWin.UserData.cellIDStr(:);
            end
            % save 
            GUIDefaults = app.mainWin.UserData.GUIDefaults;
            fNameOut    = fullfile(app.mainWin.UserData.GUIDefaults.outputDir,['GUIState_' app.mainWin.UserData.dataType(9:end) '_' num2str(app.mainWin.UserData.GUIDefaults.filename_suffix) '.mat']);
            fNameOut    = scanpix.helpers.checkSaveFile(fNameOut);
            save(fNameOut,'objData','GUIDefaults','cellIDStr');
        end

        % Menu selected function: gridnessMenu_2, 
        % rayleighvectorlengthMenu, spatialcorrelationMenu, 
        % spatialinfoMenu
        function getSpatialProps(app, event)
            ds_ind    = app.mainWin.UserData.currSelInd(1);
            % gather the appropriate data for filtering
            switch event.Source.Text
                case 'spatial info'
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.rate{1})
                        scanpix.maps.addMaps(app.mainWin.UserData.dataObj{ds_ind},'rate',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    vals = nan(sum((app.mainWin.UserData.Filter{ds_ind})),length(app.mainWin.UserData.dataObj{ds_ind}.trialNames));
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        vals(:,i) = cell2mat(cellfun(@(x,y) scanpix.analysis.spatial_info(x,y),app.mainWin.UserData.dataObj{ds_ind}.maps.rate{i}(app.mainWin.UserData.Filter{ds_ind}),app.mainWin.UserData.dataObj{ds_ind}.maps.pos{i}(app.mainWin.UserData.Filter{ds_ind}),'uni',0));
                    end
                    varName = [app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_spatInfo'];
                case 'gridness'
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{1})
                        makeSACs(app);
                    end
                    vals = nan(sum((app.mainWin.UserData.Filter{ds_ind})),3,length(app.mainWin.UserData.dataObj{ds_ind}.trialNames));
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        [~, temp]   = cellfun(@(x) scanpix.analysis.gridprops(x,'peakMode','point','corrThr',0,'radius','est'),app.mainWin.UserData.dataObj{ds_ind}.maps.sACs{i}(app.mainWin.UserData.Filter{ds_ind}),'uni',0);
                        vals(:,:,i) = cell2mat(cellfun(@(x) [x.gridness x.waveLength x.orientation],temp,'uni',0));
                    end
                    varName = [app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_gridProps'];
                    disp('Grid props output is nCells x [gridness wavelength orientation] x trials');
                case 'rayleigh vector length'
                    if isempty(app.mainWin.UserData.dataObj{ds_ind}.maps.dir{1})
                        scanpix.maps.addMaps(app.mainWin.UserData.dataObj{ds_ind},'dir',[],app.mainWin.UserData.figDefaults.mapParams);
                    end
                    vals = nan(sum((app.mainWin.UserData.Filter{ds_ind})),length(app.mainWin.UserData.dataObj{ds_ind}.trialNames));
                    for i = 1:length(app.mainWin.UserData.dataObj{ds_ind}.trialNames)
                        vals(:,i) = cell2mat(cellfun(@(x,y) scanpix.analysis.rayleighVect(x),app.mainWin.UserData.dataObj{ds_ind}.maps.dir{i}(app.mainWin.UserData.Filter{ds_ind}),'uni',0));
                    end
                    varName = [app.mainWin.UserData.dataObj{ds_ind}.dataSetName '_RV'];
                otherwise
            end
            rtn = {app.mainWin.UserData.cellIDStr{ds_ind}(app.mainWin.UserData.Filter{ds_ind}),vals};
            % export to base workspace
            assignin('base',varName,rtn);
        end

        % Menu selected function: RestoreMenu
        function RestoreMenuSelected(app, event)
            setDefaults(app,'gui');
            setDefaults(app,'fig');
            setDefaults(app,'maps');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWin = uifigure('Visible', 'off');
            app.mainWin.Name = 'UI Figure';
            app.mainWin.CloseRequestFcn = createCallbackFcn(app, @mainWinCloseRequest, true);
            app.FileMenu = uimenu(app.mainWin);
            app.FileMenu.Text = 'File';
            app.LoadDataMenu = uimenu(app.FileMenu);
            app.LoadDataMenu.Text = 'Load Data';
            app.LoadfromRawMenu = uimenu(app.LoadDataMenu);
            app.LoadfromRawMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadfromRawMenu.Text = 'from Raw';
            app.LoadfromGUIstateMenu = uimenu(app.LoadDataMenu);
            app.LoadfromGUIstateMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfromGUIstateMenuSelected, true);
            app.LoadfromGUIstateMenu.Text = 'from GUI state';
            app.DeleteDataMenu = uimenu(app.FileMenu);
            app.DeleteDataMenu.Text = 'Delete Data';
            app.DeleteDatasetsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteDatasetsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteDatasetsMenuSelected, true);
            app.DeleteDatasetsMenu.Text = 'Dataset(s)';
            app.DeleteTrialsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteTrialsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteTrialsMenuSelected, true);
            app.DeleteTrialsMenu.Text = 'Trial(s)';
            app.DeleteCellsMenu = uimenu(app.DeleteDataMenu);
            app.DeleteCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCellsMenuSelected, true);
            app.DeleteCellsMenu.Text = 'Cell(s)';
            app.ExportDataMenu = uimenu(app.FileMenu);
            app.ExportDataMenu.Text = 'Export Data';
            app.ExportFullMenu = uimenu(app.ExportDataMenu);
            app.ExportFullMenu.MenuSelectedFcn = createCallbackFcn(app, @exportData, true);
            app.ExportFullMenu.Text = 'Full';
            app.ExporCurSelMenu = uimenu(app.ExportDataMenu);
            app.ExporCurSelMenu.MenuSelectedFcn = createCallbackFcn(app, @exportData, true);
            app.ExporCurSelMenu.Text = 'Current Selection';
            app.SettingsMenu = uimenu(app.mainWin);
            app.SettingsMenu.Text = 'Settings';
            app.SetDefaultsMenu = uimenu(app.SettingsMenu);
            app.SetDefaultsMenu.Text = 'Set Defaults';
            app.GUIMenu = uimenu(app.SetDefaultsMenu);
            app.GUIMenu.MenuSelectedFcn = createCallbackFcn(app, @GUIMenuSelected, true);
            app.GUIMenu.Text = 'GUI';
            app.GeneralPlottingMenu = uimenu(app.SetDefaultsMenu);
            app.GeneralPlottingMenu.MenuSelectedFcn = createCallbackFcn(app, @GeneralPlottingMenuSelected, true);
            app.GeneralPlottingMenu.Text = 'General Plotting';
            app.RateMapsMenu = uimenu(app.SetDefaultsMenu);
            app.RateMapsMenu.MenuSelectedFcn = createCallbackFcn(app, @RateMapsMenuSelected, true);
            app.RateMapsMenu.Text = 'Rate Maps';
            app.RestoreMenu = uimenu(app.SetDefaultsMenu);
            app.RestoreMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoreMenuSelected, true);
            app.RestoreMenu.Text = 'Restore';
            app.PauseUpdatesMenu = uimenu(app.SettingsMenu);
            app.PauseUpdatesMenu.Text = 'Pause Updates';
            app.FreezeMenu = uimenu(app.PauseUpdatesMenu);
            app.FreezeMenu.MenuSelectedFcn = createCallbackFcn(app, @FreezeMenuSelected, true);
            app.FreezeMenu.Text = 'Freeze';
            app.UnFreezeMenu = uimenu(app.PauseUpdatesMenu);
            app.UnFreezeMenu.MenuSelectedFcn = createCallbackFcn(app, @UnFreezeMenuSelected, true);
            app.UnFreezeMenu.Text = 'UnFreeze';
            app.FiguresMenu = uimenu(app.mainWin);
            app.FiguresMenu.Text = 'Figures';
            app.SaveasPDFMenu = uimenu(app.FiguresMenu);
            app.SaveasPDFMenu.Enable = 'off';
            app.SaveasPDFMenu.Text = 'Save as PDF';
            app.AllMenu = uimenu(app.SaveasPDFMenu);
            app.AllMenu.Text = 'All';
            app.GUIStateMenu = uimenu(app.SaveasPDFMenu);
            app.GUIStateMenu.Text = 'GUI State';
            app.CustomSelectionMenu = uimenu(app.SaveasPDFMenu);
            app.CustomSelectionMenu.Text = 'Custom Selection';
            app.NeuropixelsMenu = uimenu(app.FiguresMenu);
            app.NeuropixelsMenu.Text = 'Neuropixels';
            app.CellsMenu = uimenu(app.NeuropixelsMenu);
            app.CellsMenu.Text = 'Cells';
            app.plotWaveformMenu = uimenu(app.CellsMenu);
            app.plotWaveformMenu.MenuSelectedFcn = createCallbackFcn(app, @plotWaveformMenuSelected, true);
            app.plotWaveformMenu.Text = 'plot Waveform';
            app.plotWaveDistonProbeMenu = uimenu(app.CellsMenu);
            app.plotWaveDistonProbeMenu.MenuSelectedFcn = createCallbackFcn(app, @plotWaveDistonProbeMenuSelected, true);
            app.plotWaveDistonProbeMenu.Text = 'plot Wave Dist on Probe';
            app.DACQMenu = uimenu(app.FiguresMenu);
            app.DACQMenu.Enable = 'off';
            app.DACQMenu.Text = 'DACQ';
            app.FilterMenu = uimenu(app.mainWin);
            app.FilterMenu.Text = 'Filter';
            app.ByClustLabelMenu = uimenu(app.FilterMenu);
            app.ByClustLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ByClustLabelMenuSelected, true);
            app.ByClustLabelMenu.Text = 'By Clust Label';
            app.BySpatialPropsMenu = uimenu(app.FilterMenu);
            app.BySpatialPropsMenu.Text = 'By Spatial Props';
            app.spatialInfoMenu = uimenu(app.BySpatialPropsMenu);
            app.spatialInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @filterByValue, true);
            app.spatialInfoMenu.Text = 'spatial Info';
            app.gridnessMenu = uimenu(app.BySpatialPropsMenu);
            app.gridnessMenu.MenuSelectedFcn = createCallbackFcn(app, @filterByValue, true);
            app.gridnessMenu.Text = 'gridness';
            app.rayleighvectorMenu = uimenu(app.BySpatialPropsMenu);
            app.rayleighvectorMenu.MenuSelectedFcn = createCallbackFcn(app, @filterByValue, true);
            app.rayleighvectorMenu.Text = 'rayleigh vector';
            app.MakeCustomFilterMenu = uimenu(app.FilterMenu);
            app.MakeCustomFilterMenu.Enable = 'off';
            app.MakeCustomFilterMenu.Text = 'Make Custom Filter ';
            app.RemoveFilterMenu = uimenu(app.FilterMenu);
            app.RemoveFilterMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveFilterMenuSelected, true);
            app.RemoveFilterMenu.Enable = 'off';
            app.RemoveFilterMenu.Text = 'Remove FIlter';
            app.AnalysisMenu = uimenu(app.mainWin);
            app.AnalysisMenu.Text = 'Analysis';
            app.spatialpropsMenu = uimenu(app.AnalysisMenu);
            app.spatialpropsMenu.Text = 'spatial props';
            app.gridnessMenu_2 = uimenu(app.spatialpropsMenu);
            app.gridnessMenu_2.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.gridnessMenu_2.Text = 'gridness';
            app.spatialinfoMenu = uimenu(app.spatialpropsMenu);
            app.spatialinfoMenu.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.spatialinfoMenu.Text = 'spatial info';
            app.rayleighvectorlengthMenu = uimenu(app.spatialpropsMenu);
            app.rayleighvectorlengthMenu.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.rayleighvectorlengthMenu.Text = 'rayleigh vector length';
            app.spatialcorrelationMenu = uimenu(app.spatialpropsMenu);
            app.spatialcorrelationMenu.MenuSelectedFcn = createCallbackFcn(app, @getSpatialProps, true);
            app.spatialcorrelationMenu.Enable = 'off';
            app.spatialcorrelationMenu.Text = 'spatial correlation';
            app.TrialsinDatasetListBoxLabel = uilabel(app.mainWin);
            app.TrialsinDatasetListBoxLabel.Text = {'Trials'; 'in'; 'Dataset'};
            app.TrialsinDatasetListBox = uilistbox(app.mainWin);
            app.TrialsinDatasetListBox.Items = {};
            app.TrialsinDatasetListBox.Multiselect = 'on';
            app.TrialsinDatasetListBox.ValueChangedFcn = createCallbackFcn(app, @TrialsinDatasetListBoxValueChanged, true);
            app.TrialsinDatasetListBox.Value = {};
            app.CellsinDatasetListBoxLabel = uilabel(app.mainWin);
            app.CellsinDatasetListBoxLabel.Text = {'Cells'; 'in'; 'Dataset'};
            app.CellsinDatasetListBox = uilistbox(app.mainWin);
            app.CellsinDatasetListBox.Items = {};
            app.CellsinDatasetListBox.Multiselect = 'on';
            app.CellsinDatasetListBox.ValueChangedFcn = createCallbackFcn(app, @CellsinDatasetListBoxValueChanged, true);
            app.CellsinDatasetListBox.Value = {};
            app.ExportDataButton = uibutton(app.mainWin, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @exportData, true);
            app.ExportDataButton.Text = 'Export Data';
            app.TabGroup = uitabgroup(app.mainWin);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.wfs = uiaxes(app.OverviewTab);
            title(app.wfs, 'Waveform')
            xlabel(app.wfs, 'time (ms)')
            ylabel(app.wfs, '')
            app.wfs.XTick = [];
            app.wfs.YTick = [];
            app.ac_shortLag = uiaxes(app.OverviewTab);
            title(app.ac_shortLag, '')
            xlabel(app.ac_shortLag, 'time (ms)')
            ylabel(app.ac_shortLag, '')
            app.ac_shortLag.XLim = [-0.02 0.02];
            app.ac_shortLag.XTick = [];
            app.ac_shortLag.XTickLabel = '';
            app.ac_shortLag.YTick = [];
            app.spikepos = uiaxes(app.OverviewTab);
            title(app.spikepos, 'Spike Pos')
            xlabel(app.spikepos, '')
            ylabel(app.spikepos, '')
            app.spikepos.XTick = [];
            app.spikepos.YTick = [];
            app.rmap = uiaxes(app.OverviewTab);
            title(app.rmap, 'Rate Map')
            xlabel(app.rmap, '')
            ylabel(app.rmap, '')
            app.rmap.XTick = [];
            app.rmap.YTick = [];
            app.varmap = uiaxes(app.OverviewTab);
            title(app.varmap, 'Var. Data Plot')
            xlabel(app.varmap, '')
            ylabel(app.varmap, '')
            app.varmap.XTick = [];
            app.varmap.YTick = [];
            app.dmap = uiaxes(app.OverviewTab);
            title(app.dmap, 'Dir Map')
            xlabel(app.dmap, '')
            ylabel(app.dmap, '')
            app.dmap.XTick = [];
            app.dmap.YTick = [];
            app.nSpikesTxt = uieditfield(app.OverviewTab, 'text');
            app.nSpikesTxt.Editable = 'off';
            app.nSpikesTxt.Value = 'nSpikes:';
            app.nSpikes = uieditfield(app.OverviewTab, 'numeric');
            app.nSpikes.Editable = 'off';
            app.amplitudeTxt = uieditfield(app.OverviewTab, 'text');
            app.amplitudeTxt.Editable = 'off';
            app.amplitudeTxt.Value = 'amplitude:';
            app.isodTxt = uieditfield(app.OverviewTab, 'text');
            app.isodTxt.Editable = 'off';
            app.isodTxt.Value = 'iso D:';
            app.lratioTxt = uieditfield(app.OverviewTab, 'text');
            app.lratioTxt.Editable = 'off';
            app.lratioTxt.Value = 'L-ratio:';
            app.amplitude = uieditfield(app.OverviewTab, 'numeric');
            app.amplitude.Editable = 'off';
            app.lratio = uieditfield(app.OverviewTab, 'numeric');
            app.lratio.Editable = 'off';
            app.isod = uieditfield(app.OverviewTab, 'numeric');
            app.isod.Editable = 'off';
            app.MakeRMapsButton = uibutton(app.OverviewTab, 'push');
            app.MakeRMapsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeRMapsButtonPushed, true);
            app.MakeRMapsButton.Text = 'Make Maps';
            app.varmapButton = uibutton(app.OverviewTab, 'push');
            app.varmapButton.ButtonPushedFcn = createCallbackFcn(app, @varmapButtonPushed, true);
            app.varmapButton.Text = 'Make Plots';
            app.LoadWaveForms = uibutton(app.OverviewTab, 'push');
            app.LoadWaveForms.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveFormsPushed, true);
            app.LoadWaveForms.Enable = 'off';
            app.LoadWaveForms.Visible = 'off';
            app.LoadWaveForms.Text = {'Load '; 'Waveforms'};
            app.MakeDMapsButton = uibutton(app.OverviewTab, 'push');
            app.MakeDMapsButton.ButtonPushedFcn = createCallbackFcn(app, @MakeRMapsButtonPushed, true);
            app.MakeDMapsButton.Text = 'Make Maps';
            app.ac_longLag = uiaxes(app.OverviewTab);
            title(app.ac_longLag, '')
            xlabel(app.ac_longLag, 'time (ms)')
            ylabel(app.ac_longLag, '')
            app.ac_longLag.XLim = [-0.02 0.02];
            app.ac_longLag.XTick = [];
            app.ac_longLag.XTickLabel = '';
            app.ac_longLag.YTick = [];
            app.PanelAllTrials = uipanel(app.OverviewTab);
            app.SelectPlotstyleLabel = uilabel(app.OverviewTab);
            app.SelectPlotstyleLabel.Text = 'Select Plotstyle';
            app.varMapList = uilistbox(app.OverviewTab);
            app.varMapList.Items = {'Spatial AC', 'Object Vect', 'Speed Mod.'};
            app.varMapList.ValueChangedFcn = createCallbackFcn(app, @varMapListValueChanged, true);
            app.varMapList.Value = 'Spatial AC';
            app.cluLabelTxt = uieditfield(app.OverviewTab, 'text');
            app.cluLabelTxt.Editable = 'off';
            app.cluLabelTxt.Value = 'clust. label: ';
            app.cluLabel = uieditfield(app.OverviewTab, 'text');
            app.cluLabel.Value = 'n/a';
            app.CompareCellsTab = uitab(app.TabGroup);
            app.CompareCellsTab.Title = 'Compare Cells';
            app.ac1 = uiaxes(app.CompareCellsTab);
            title(app.ac1, '')
            xlabel(app.ac1, '')
            ylabel(app.ac1, '')
            app.ac1.XLim = [-0.02 0.02];
            app.ac1.XTick = [];
            app.ac1.XTickLabel = '';
            app.ac1.YTick = [];
            app.wf3 = uiaxes(app.CompareCellsTab);
            title(app.wf3, '')
            xlabel(app.wf3, '')
            ylabel(app.wf3, '')
            app.wf3.XLim = [-0.02 0.02];
            app.wf3.XTick = [];
            app.wf3.XTickLabel = '';
            app.wf3.YTick = [];
            app.cg2_3 = uiaxes(app.CompareCellsTab);
            title(app.cg2_3, '')
            xlabel(app.cg2_3, '')
            ylabel(app.cg2_3, '')
            app.cg2_3.XLim = [-0.02 0.02];
            app.cg2_3.XTick = [];
            app.cg2_3.XTickLabel = '';
            app.cg2_3.YTick = [];
            app.cg1_3 = uiaxes(app.CompareCellsTab);
            title(app.cg1_3, '')
            xlabel(app.cg1_3, '')
            ylabel(app.cg1_3, '')
            app.cg1_3.XLim = [-0.02 0.02];
            app.cg1_3.XTick = [];
            app.cg1_3.XTickLabel = '';
            app.cg1_3.YTick = [];
            app.ac2 = uiaxes(app.CompareCellsTab);
            title(app.ac2, '')
            xlabel(app.ac2, '')
            ylabel(app.ac2, '')
            app.ac2.XLim = [-0.02 0.02];
            app.ac2.XTick = [];
            app.ac2.XTickLabel = '';
            app.ac2.YTick = [];
            app.cg1_2 = uiaxes(app.CompareCellsTab);
            title(app.cg1_2, '')
            xlabel(app.cg1_2, '')
            ylabel(app.cg1_2, '')
            app.cg1_2.XLim = [-0.02 0.02];
            app.cg1_2.XTick = [];
            app.cg1_2.XTickLabel = '';
            app.cg1_2.YTick = [];
            app.wf1 = uiaxes(app.CompareCellsTab);
            title(app.wf1, '')
            xlabel(app.wf1, '')
            ylabel(app.wf1, '')
            app.wf1.XLim = [-0.02 0.02];
            app.wf1.XTick = [];
            app.wf1.XTickLabel = '';
            app.wf1.YTick = [];
            app.wf2 = uiaxes(app.CompareCellsTab);
            title(app.wf2, '')
            xlabel(app.wf2, '')
            ylabel(app.wf2, '')
            app.wf2.XLim = [-0.02 0.02];
            app.wf2.XTick = [];
            app.wf2.XTickLabel = '';
            app.wf2.YTick = [];
            app.CC_cell_ID1 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID1.Editable = 'off';
            app.CC_cell_ID2 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID2.Editable = 'off';
            app.binSzEditFieldLabel = uilabel(app.CompareCellsTab);
            app.binSzEditFieldLabel.Text = 'binSz';
            app.CC_binSzEditField = uieditfield(app.CompareCellsTab, 'numeric');
            app.CC_binSzEditField.Limits = [0 Inf];
            app.CC_binSzEditField.ValueChangedFcn = createCallbackFcn(app, @CC_binSzEditFieldValueChanged, true);
            app.lagEditFieldLabel = uilabel(app.CompareCellsTab);
            app.lagEditFieldLabel.Text = 'lag';
            app.CC_lagEditField = uieditfield(app.CompareCellsTab, 'numeric');
            app.CC_lagEditField.Limits = [0 Inf];
            app.CC_lagEditField.ValueChangedFcn = createCallbackFcn(app, @CC_lagEditFieldValueChanged, true);
            app.ac3 = uiaxes(app.CompareCellsTab);
            title(app.ac3, '')
            xlabel(app.ac3, '')
            ylabel(app.ac3, '')
            app.ac3.XLim = [-0.02 0.02];
            app.ac3.XTick = [];
            app.ac3.XTickLabel = '';
            app.ac3.YTick = [];
            app.CC_cell_ID3 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID3.Editable = 'off';
            app.CC_cell_ID2_2 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID2_2.Editable = 'off';
            app.CC_cell_ID3_3 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID3_3.Editable = 'off';
            app.TextArea = uitextarea(app.CompareCellsTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Correlogram'; 'props'};
            app.PlotCGSelectionButton = uibutton(app.CompareCellsTab, 'push');
            app.PlotCGSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCGSelectionButtonPushed, true);
            app.PlotCGSelectionButton.Text = 'Plot CG Selection';
            app.PlotWFSelectionButton = uibutton(app.CompareCellsTab, 'push');
            app.PlotWFSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotWFSelectionButtonPushed, true);
            app.PlotWFSelectionButton.Text = 'Plot WF Selection';
            app.PlotClusterSpaceButton = uibutton(app.CompareCellsTab, 'push');
            app.PlotClusterSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotClusterSpaceButtonPushed, true);
            app.PlotClusterSpaceButton.Text = 'Plot Cluster Space';
            app.CC_cell_ID1_1 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_cell_ID1_1.Editable = 'off';
            app.CC_maxChanCell1 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_maxChanCell1.Editable = 'off';
            app.CC_maxChanCell2 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_maxChanCell2.Editable = 'off';
            app.CC_maxChanCell3 = uieditfield(app.CompareCellsTab, 'text');
            app.CC_maxChanCell3.Editable = 'off';
            app.LineariseItTab = uitab(app.TabGroup);
            app.LineariseItTab.Title = 'Linearise It';
            app.TextArea_2 = uitextarea(app.LineariseItTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Track properties'};
            app.TrackLengthcmEditFieldLabel = uilabel(app.LineariseItTab);
            app.TrackLengthcmEditFieldLabel.Text = 'Track Length (cm)';
            app.TrackLengthEditField = uieditfield(app.LineariseItTab, 'text');
            app.TrackTypeDropDownLabel = uilabel(app.LineariseItTab);
            app.TrackTypeDropDownLabel.Text = 'Track Type';
            app.TrackTypeDropDown = uidropdown(app.LineariseItTab);
            app.TrackTypeDropDown.Items = {'square track', 'linear track'};
            app.TrackTypeDropDown.Value = 'square track';
            app.LineariseItButton = uibutton(app.LineariseItTab, 'push');
            app.LineariseItButton.ButtonPushedFcn = createCallbackFcn(app, @LineariseItButtonPushed, true);
            app.LineariseItButton.Text = 'Linearise It';
            app.lin_allCells = uiaxes(app.LineariseItTab);
            title(app.lin_allCells, '')
            xlabel(app.lin_allCells, 'Position (cm)')
            ylabel(app.lin_allCells, {'Cell N'; ''})
            app.lin_allCells.XTick = [];
            app.lin_allCells.YTick = [];
            app.lin_spikebypos = uiaxes(app.LineariseItTab);
            title(app.lin_spikebypos, '')
            xlabel(app.lin_spikebypos, 'Position (cm)')
            ylabel(app.lin_spikebypos, 'Time (s)')
            app.lin_spikebypos.XTick = [];
            app.lin_spikebypos.YTick = [];
            app.lin_rate = uiaxes(app.LineariseItTab);
            title(app.lin_rate, '')
            xlabel(app.lin_rate, 'Position (cm)')
            ylabel(app.lin_rate, 'Firing Rate (Hz)')
            app.lin_rate.XTick = [];
            app.lin_rate.YTick = [];
            app.Lin_cellID = uieditfield(app.LineariseItTab, 'text');
            app.Lin_cellID.Editable = 'off';
            app.TextArea_3 = uitextarea(app.LineariseItTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'All Cells'};
            app.Lin_trialID = uieditfield(app.LineariseItTab, 'text');
            app.Lin_trialID.Editable = 'off';
            app.RundirectionDropDownLabel = uilabel(app.LineariseItTab);
            app.RundirectionDropDownLabel.Text = 'Run direction';
            app.RundirectionDropDown = uidropdown(app.LineariseItTab);
            app.RundirectionDropDown.Items = {'all', 'CW', 'CCW'};
            app.RundirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @RundirectionDropDownValueChanged, true);
            app.RundirectionDropDown.Value = 'all';
            app.PlotSelectionButton = uibutton(app.mainWin, 'push');
            app.PlotSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSelectionButtonPushed, true);
            app.PlotSelectionButton.Text = 'Plot Selection';
            app.LoadDataButton = uibutton(app.mainWin, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DatasetLabel = uilabel(app.mainWin);
            app.DatasetLabel.Text = 'Dataset';
            app.CurrentDatasetEditField = uieditfield(app.mainWin, 'text');
            app.CurrentDatasetEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentDatasetEditFieldValueChanged2, true);
            app.TrialEditFieldLabel = uilabel(app.mainWin);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.mainWin, 'text');
            app.TrialEditField.Editable = 'off';
            app.CellEditFieldLabel = uilabel(app.mainWin);
            app.CellEditFieldLabel.Text = 'Cell #';
            app.CellEditField = uieditfield(app.mainWin, 'text');
            app.CellEditField.Editable = 'off';
            app.SelectDatasetsListBoxLabel = uilabel(app.mainWin);
            app.SelectDatasetsListBoxLabel.Text = {'Select'; 'Datasets'};
            app.SelectDatasetsListBox = uilistbox(app.mainWin);
            app.SelectDatasetsListBox.Items = {};
            app.SelectDatasetsListBox.Multiselect = 'on';
            app.SelectDatasetsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectDatasetsListBoxValueChanged, true);
            app.SelectDatasetsListBox.Value = {};
            app.SelectAllCells = uibutton(app.mainWin, 'push');
            app.SelectAllCells.ButtonPushedFcn = createCallbackFcn(app, @SelectAllCellsPushed, true);
            app.SelectAllCells.Text = {'Select'; 'All'};
            app.SelectAllTrials = uibutton(app.mainWin, 'push');
            app.SelectAllTrials.ButtonPushedFcn = createCallbackFcn(app, @SelectAllTrialsPushed, true);
            app.SelectAllTrials.Text = {'Select'; 'All'};
            app.ExportFiguresButton = uibutton(app.mainWin, 'push');
            app.ExportFiguresButton.Enable = 'off';
            app.ExportFiguresButton.Text = 'Export Figures';
            app.Button_2 = uibutton(app.mainWin, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.mainWin, 'push');
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.SelectAllDatasets = uibutton(app.mainWin, 'push');
            app.SelectAllDatasets.ButtonPushedFcn = createCallbackFcn(app, @SelectAllDatasetsButtonPushed, true);
            app.SelectAllDatasets.Text = {'Select'; 'All'};
            % Show the figure after all components are created
            app.mainWin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWin)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWin)
        end
    end
end